classdef browser_batch_export_curves_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel1         matlab.ui.container.Panel
        channelSelector  matlab.ui.control.ListBox
        exportCurves     matlab.ui.control.Button
    end

    
% Copyright ©2021. Femtonics Kft. (Femtonics). All Rights Reserved. 
% Permission to use, copy, modify this software and its documentation for educational,
% research, and not-for-profit purposes, without fee and without a signed licensing agreement, is 
% hereby granted, provided that the above copyright notice, this paragraph and the following two 
% paragraphs appear in all copies, modifications, and distributions. Contact info@femtonics.eu
% for commercial licensing opportunities.
% 
% IN NO EVENT SHALL FEMTONICS BE LIABLE TO ANY PARTY FOR DIRECT, INDIRECT, SPECIAL, 
% INCIDENTAL, OR CONSEQUENTIAL DAMAGES, INCLUDING LOST PROFITS, ARISING OUT OF 
% THE USE OF THIS SOFTWARE AND ITS DOCUMENTATION, EVEN IF FEMTONICS HAS BEEN 
% ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
% 
% FEMTONICS SPECIFICALLY DISCLAIMS ANY WARRANTIES, INCLUDING, BUT NOT LIMITED TO, 
% THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR 
% PURPOSE. THE SOFTWARE AND ACCOMPANYING DOCUMENTATION, IF ANY, PROVIDED 
% HEREUNDER IS PROVIDED "AS IS". FEMTONICS HAS NO OBLIGATION TO PROVIDE 
% MAINTENANCE, SUPPORT, UPDATES, ENHANCEMENTS, OR MODIFICATIONS.
    
    properties (Access = private)
        %fileName = '';
        %absolutePath = '';
        %ext = '';
        selectedChannelsList = {}; 
        callingApp % Description
    end
    
    methods (Access = private)
%         function initialize_gui(app, hObject, handles, isreset)
%             
% %             if isfield(handles, 'metricdata') && ~isreset
% %                 return;
% %             end
%             
%             %app.selectedChannelsList = {};
%             %set(hObject,'WindowStyle','modal')
%             
%             %set(app.channelSelector,'Items',app.channelList);
%             %set(app.channelSelector,'Max',length(app.channelList),'Min',0);
%             
%             % Update handles structure
%             %guidata(app.figure1, handles);
%             %guidata(hObject, handles);
%         end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function browser_batch_export_curves_OpeningFcn(app, mainApp, channelList)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to browser_batch_export_curves (see VARARGIN)
            
            % Choose default command line output for browser_batch_export_curves
            %app.output = hObject;
            
            app.callingApp = mainApp;
            % validate and set channel list in channelSelector listbox
            %channelList = varargin{1};
            validateattributes(channelList,{'cell'},{'vector'});
            %set(app.channelList,'Items',channelList);
            
            % save parent haandle
            %app.parent = varargin{2};
            
            % initialize output arguments
            %app.fileName = '';
            %app.absolutePath = '';
            %app.ext = '';
            app.selectedChannelsList = {};
            
            % Update handles structure
            %guidata(hObject, handles);
            
            %initialize_gui(hObject, handles, false);
            %set(hObject,'WindowStyle','modal')
      
            set(app.channelSelector,'Items',channelList);
            set(app.channelSelector,'ItemsData',[1:length(channelList)]);
            
            % UIWAIT makes browser_batch_export_curves wait for user response (see UIRESUME)
            uiwait(app.figure1);
        end

        % Value changed function: channelSelector
        function channelSelector_Callback(app, event)
            % hObject    handle to channelSelector (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of channelSelector as text
            %        str2double(get(hObject,'String')) returns contents of channelSelector as a double          
           
            % update selected channels
            channelList = app.channelSelector.Items;
            app.selectedChannelsList = channelList(app.channelSelector.Value);
        end

        % Button pushed function: exportCurves
        function exportCurves_Callback(app, event)
            % hObject    handle to exportCurves (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(isempty(app.selectedChannelsList))
                uiwait(msgbox({'No channels are selected to export. Please select at least one channel from the list.'}));
                return;
            end
            
            % file path selector, and enter batch file name for export
            filter = {'*.txt';'*.mat'};
            
            extIdx = length(filter) + 1;
            while(extIdx > length(filter))
                [fileName, absolutePath, extIdx] = uiputfile(filter,'Select path and file type to save','exported_curves');
                if(fileName == 0)
                    return;
                end
            end
            
            ext = strrep(filter{extIdx},'*','');
            fileName = strrep(fileName,ext,'');
            absolutePath = strrep(absolutePath,'\','/');         
            
            % update calling app properties 
            app.callingApp.selectedChannelsForExport = app.selectedChannelsList;
            app.callingApp.fileName = fileName;
            app.callingApp.absolutePath = absolutePath;
            app.callingApp.ext = ext;
            
            % close ui
            close(app.figure1);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
           
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Export curves';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Common channels of selected measurement units ';
            app.channelSelector = uilistbox(app.uipanel1);
            app.channelSelector.Items = {};
            app.channelSelector.Multiselect = 'on';
            app.channelSelector.ValueChangedFcn = createCallbackFcn(app, @channelSelector_Callback, true);
            app.channelSelector.Value = {};
            app.exportCurves = uibutton(app.uipanel1, 'push');
            app.exportCurves.ButtonPushedFcn = createCallbackFcn(app, @exportCurves_Callback, true);
            app.exportCurves.Text = 'Export curves of selected channels...';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = browser_batch_export_curves_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)browser_batch_export_curves_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end