classdef femtoapi_browser_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        measTreeUipanel                matlab.ui.container.Panel
        uitreeObj                      matlab.ui.container.Tree
        infoTableUiPanel               matlab.ui.container.Panel
        infoTable                      matlab.ui.control.Table
        uipanel6                       matlab.ui.container.Panel
        refreshDataButton              matlab.ui.control.Button
        batchExportInputCurvesButton   matlab.ui.control.Button
        batchExportOutputCurvesButton  matlab.ui.control.Button
        batchExportMeasurementParamsButton  matlab.ui.control.Button
        uipanel7                       matlab.ui.container.Panel
        text2                          matlab.ui.control.Label
        lutChannelsPopupMenu           matlab.ui.control.DropDown
        text3                          matlab.ui.control.Label
        slider1                        matlab.ui.control.Slider
        frameNumText                   matlab.ui.control.EditField
        errorMessageText               matlab.ui.control.Label
        axes1                          matlab.ui.control.UIAxes
    end


% Copyright ©2021. Femtonics Kft. (Femtonics). All Rights Reserved. 
% Permission to use, copy, modify this software and its documentation for educational,
% research, and not-for-profit purposes, without fee and without a signed licensing agreement, is 
% hereby granted, provided that the above copyright notice, this paragraph and the following two 
% paragraphs appear in all copies, modifications, and distributions. Contact info@femtonics.eu
% for commercial licensing opportunities.
% 
% IN NO EVENT SHALL FEMTONICS BE LIABLE TO ANY PARTY FOR DIRECT, INDIRECT, SPECIAL, 
% INCIDENTAL, OR CONSEQUENTIAL DAMAGES, INCLUDING LOST PROFITS, ARISING OUT OF 
% THE USE OF THIS SOFTWARE AND ITS DOCUMENTATION, EVEN IF FEMTONICS HAS BEEN 
% ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
% 
% FEMTONICS SPECIFICALLY DISCLAIMS ANY WARRANTIES, INCLUDING, BUT NOT LIMITED TO, 
% THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR 
% PURPOSE. THE SOFTWARE AND ACCOMPANYING DOCUMENTATION, IF ANY, PROVIDED 
% HEREUNDER IS PROVIDED "AS IS". FEMTONICS HAS NO OBLIGATION TO PROVIDE 
% MAINTENANCE, SUPPORT, UPDATES, ENHANCEMENTS, OR MODIFICATIONS.
    
    properties (Access = private)
        femtoapiObj    FemtoAPIProcessing
        frameNum       uint32
        treeNodeStruct
        xLabel % Description
        yLabel % Description
        xTicks % Description
        yTicks % Description
        xlabelText % Description
        ylabelText % Description
        image % Description
        batchExportApp % Description
    end
    % output parameters of opened window
    properties (Access = public)
        fileName
        ext
        absolutePath
        selectedChannelsForExport
    end
    
    methods (Access = private)
        
        function setInfoText(app)
            infoStr = struct;
            infoStr.column1 = "";
            infoStr.column2 = "";
            
            if(~isempty(app.uitreeObj.SelectedNodes))
                
                if(~isfield(app.treeNodeStruct,'handle'))
                    error('Internal error: tree node structure has no handle field.'); % should not occur
                end
                
                validateattributes(app.treeNodeStruct.handle,{'numeric'},{'vector','nonempty','nonnegative','integer'});

                if(length(app.treeNodeStruct.handle) == 1)
                    % type: file handle
                    if(isfield(app.treeNodeStruct,'measurementSessions'))
                        infoStr.column1 = string({"Item type";"Path";"Status";"Is modified";"Is compressible";...
                            "Created";"Modified";"Saved";"Measurement sessions"});
                        
                        infoStr.column2 = string({"MESc file"; app.treeNodeStruct.path; app.treeNodeStruct.status;...
                            app.treeNodeStruct.isModified; app.treeNodeStruct.isCompressible; app.treeNodeStruct.created;...
                            app.treeNodeStruct.modified; app.treeNodeStruct.saved; length(app.treeNodeStruct.measurementSessions)});
                    end
                elseif(length(app.treeNodeStruct.handle) == 2)
                    % type: measurement session
                    if(isfield(app.treeNodeStruct,'measurements'))
                        infoStr.column1 = string({"Item type"; "Measurements"});
                        infoStr.column2 = string({"Measurement session"; ...
                            length(app.treeNodeStruct.measurements)} );
                    else
                        % file is new -> no measurement field
                        infoStr.column1 = string({"Item type"; "Measurements"});
                        infoStr.column2 = string({"Measurement session"; 0});
                    end
                else
                    if(~isfield(app.treeNodeStruct,'type'))
                        error('Internal error: tree node structure should have ''type'' field.');
                    end
                    % type can be 'group' or 'measurement'
                    if( strcmp(app.treeNodeStruct.type,'group') )
                        infoStr.column1 = string({"Item type";"Date";"Creator";"Creator revision";...
                            "Profile name";"User name";"Host name";"Setup name";...
                            "Measurement1"});
                        infoStr.column2 = string({"Measurement group"; app.treeNodeStruct.date; app.treeNodeStruct.creatingMEScVersion;...
                            app.treeNodeStruct.creatingMEScRevision; app.treeNodeStruct.profileName;...
                            app.treeNodeStruct.userName; app.treeNodeStruct.hostName; app.treeNodeStruct.setupName;...
                            length(app.treeNodeStruct.measurements)});
                        
                    elseif( strcmp(app.treeNodeStruct.type,'measurement') )
                        % handle length >= 3 : measurement unit: group or measurement
                        format long;
                        %extra data from measurementParamsXml
                        devlist = {};
                        measurementParamsXMLString = regexprep(app.treeNodeStruct.measurementInfo.measurementParamsXML,'".m"','"um"');
                        measurementParamsXMLString = regexprep(measurementParamsXMLString,'axis attribute="Unit" id="TiltY" value="."','axis attribute="Unit" id="TiltY" value="u"');
                        measurementParamsXMLString = regexprep(measurementParamsXMLString,'axis attribute="Unit" id="TiltX" value="."','axis attribute="Unit" id="TiltX" value="u"');
                        dom = xml2struct(measurementParamsXMLString);
                        for i=1: length(dom.Task.Devices.Gear)
                            gear = dom.Task.Devices.Gear{i};
                            gearname = gear.Attributes.name;
                            geartype = gear.Attributes.type;
                            if geartype == "PMT"
                                valueOfInterest = "reference_voltage";
                            elseif geartype == "PockelsCell"
                                valueOfInterest = "intensity";
                            elseif geartype ==  "APTMotor"
                                valueOfInterest = "position";
                            end
                            %disp(gear)
                            for j=1: length(gear.param)
                                if gear.param{j}.Attributes.name == valueOfInterest
                                    %disp(gear.param{j}.Attributes.name);
                                    %disp(gear.param{j}.Attributes.value);
                                    devlist{end+1} = [gearname + " " + valueOfInterest; gear.param{j}.Attributes.value];
                                end
                            end
                        end

                        
                        col1lst = {"Item type";"Date";"Creator";"Creator revision";...
                            "Profile name";"User name";"Host name";"Setup name";...
                            "is being recorded";"Measurement type";"Dimensions";"Channels";"Bits per sample";...
                            "XY pixel size";"Z from the zero level";"duration"};
                        for i=1: length(devlist)
                            col1lst{end+1} = devlist{i}{1};
                        end
                        infoStr.column1 = string(col1lst);
                        
                        xyPixelSize = sprintf('%.5f %s x %.5f %s',app.treeNodeStruct.dimensions(1).conversion.scale,...
                            app.treeNodeStruct.dimensions(1).conversion.unitName, ...
                            app.treeNodeStruct.dimensions(2).conversion.scale,...
                            app.treeNodeStruct.dimensions(2).conversion.unitName);
                        zFromZeroLevel = sprintf('%.2f %s',app.treeNodeStruct.labelingOrigin(3),...
                            app.treeNodeStruct.dimensions(1).conversion.unitName);
                        
                        dimSize = numel(app.treeNodeStruct.dimensions);
                        dimensions = strcat('%d',repmat(' x %d',1,dimSize-1));
                        dimensions = sprintf(dimensions,app.treeNodeStruct.dimensions(1:dimSize).size);
                        channelNum = length(app.treeNodeStruct.channels);
                        channelNames = [];
                        if(channelNum > 0)
                            for i=1:channelNum
                                channelNames = strcat(channelNames, sprintf('%s,',app.treeNodeStruct.channels(i).data.name));
                            end
                            %remove ',' from the end
                            channelNames(end) = [];
                        end
                        
                        duration = app.treeNodeStruct.measurementInfo.duration;
                        if(~isnumeric(duration))
                            error('Measurement duration must be an integer number.');
                        end
                        if(duration > 0)
                            duration = sprintf('%.1f s',duration/1000);
                        else
                            duration = "N/A";
                        end
                        
                        bitsPerSample = app.treeNodeStruct.measurementInfo.bitsPerSample;
                        if(~isnumeric(bitsPerSample))
                            error('Bits per sample must be a positive numeric value');
                        end
                        if(bitsPerSample > 0)
                            bitsPerSample = sprintf('%d',bitsPerSample);
                        else
                            bitsPerSample = "N/A";
                        end
                        
                        col2lst = {"Measurement"; app.treeNodeStruct.date; app.treeNodeStruct.creatingMEScVersion;...
                            app.treeNodeStruct.creatingMEScRevision; app.treeNodeStruct.profileName;...
                            app.treeNodeStruct.userName; app.treeNodeStruct.hostName; app.treeNodeStruct.setupName;...
                            app.treeNodeStruct.isBeingRecorded; app.treeNodeStruct.measurementType; dimensions; ....
                            channelNames; bitsPerSample;...
                            xyPixelSize; zFromZeroLevel; duration};
                        
                        for i=1: length(devlist)
                            col2lst{end+1} = str2num(devlist{i}{2});
                        end
                        
                        infoStr.column2 = string(col2lst);

                    end
                end
            end
            set(app.infoTable,'RowName',[]);
            set(app.infoTable,'Data',cellstr([infoStr.column1 infoStr.column2]));
        end
        
        
        function show_frame(app)
            % do not show anything if clicked handle is not a channel handle
            if(isempty(app.uitreeObj.SelectedNodes) || ...
                    ~isfield(app.treeNodeStruct,'channels') || ...
                    ~ismember(length(app.treeNodeStruct.dimensions),[2,3]))
                app.axes1.reset;
                imshow([],'Parent',app.axes1)
                return;
            end
            
            if(isfield(app.treeNodeStruct,'channels'))
                % get the channel number selected from the popupmenu
                chnum = get(app.lutChannelsPopupMenu,'Value');
                % for debug
                if(length(app.treeNodeStruct.channels) < chnum)
                    error(['Internal error: The selected channel number should be less'...
                        'than or equal to the number of channels']);
                end

                app.image = readChannelData(app.femtoapiObj, (app.treeNodeStruct.channels(chnum).data.handle)',...
                    'uint16', {[1:app.treeNodeStruct.dimensions(1).size], ...
                    [1:app.treeNodeStruct.dimensions(2).size],[app.frameNum:app.frameNum] } );
                
                lutObj = LUT(app.treeNodeStruct.channels(chnum).data.clut);
                app.image = lutObj.convertChannelToARGB(app.image);
                
                app.axes1.reset;
                app.axes1.XAxisLocation = 'bottom';
                app.axes1.YAxisLocation = 'left';               
                app.axes1.XLimMode = 'manual';
                app.axes1.XLim = [-Inf inf]; %[0 24];
                app.axes1.XTickMode = 'manual';
                app.axes1.XTick = app.xTicks;
                
                app.axes1.XTickLabelMode = 'manual';
                app.axes1.XTickLabel = sprintfc('%d',fix(app.xLabel)); 
                app.axes1.XMinorTick = 'On';                 

                app.axes1.YLimMode = 'manual';
                app.axes1.YLim = [-Inf inf];
                app.axes1.YTickMode = 'manual';
                app.axes1.YTick = app.yTicks; 
                app.axes1.YTickLabelMode = 'manual';                
                app.axes1.YTickLabel = sprintfc('%d',fliplr(fix(app.yLabel))); 
                app.axes1.YMinorTick = 'On';
                               
                xlabel(app.axes1,app.xlabelText);
                ylabel(app.axes1,app.ylabelText);
                               
                imagesc(app.axes1,app.image(:,:,2:4));
                

            end
        end
        
        
        function buildUITree(app)
            app.uitreeObj = uitree(app.measTreeUipanel);
            app.uitreeObj.Multiselect = 'on';
            app.uitreeObj.SelectionChangedFcn = createCallbackFcn(app, @uitreeObjSelectionChanged, true);
            app.uitreeObj.Position = [0 0 423 348];
            uitreeRoot = app.uitreeObj;
            app.buildUITreeHelper(uitreeRoot,1, app.femtoapiObj.m_processingStateTreeObj);
        end
        
        
        function buildUITreeHelper(app, parentUiNode, nodeID, femtoapiTreeObj)
            % do not want 'channels' in tree view
            if(isfield(femtoapiTreeObj.getStructByNodeID(nodeID),'channels'))
                return;
            end
            
            % get children of current node, and add them to ui tree
            iterator = femtoapiTreeObj.m_tree.getchildren(nodeID);
            if(isempty(iterator))
                return;
            end
            
            for i = 1:length(iterator)
                % only create new node when it is not a channel handle
                structNodeRef = femtoapiTreeObj.getStructByNodeID(iterator(i));
                fileHandle = structNodeRef.handle;
                nodeStr = strrep(strcat('(',sprintf('%d,',fileHandle),')'),',)',')');
                
                if(length(fileHandle) == 1 && isfield(structNodeRef,'path') )
                    % no comment, place file name after handle string
                    [~,filename,ext] = fileparts(structNodeRef.path);
                    nodeStr = strcat([nodeStr,' ',filename,ext]);
                elseif(isfield(structNodeRef,'comment'))
                    nodeStr = strcat([nodeStr,' ',structNodeRef.comment]);
                end
                
                childUiNode = uitreenode(parentUiNode,'Text',nodeStr,...
                    'NodeData',structNodeRef.handle);
                
                app.buildUITreeHelper(childUiNode, iterator(i), femtoapiTreeObj );
            end
        end % end function


        function selectedMUnitNodes = checkSelectedMUnitNodes(app)
            selectedNodes = app.uitreeObj.SelectedNodes;
            if(isempty(selectedNodes))
                set(handles.errorMessageText,'String',['Could not batch export curves, '...
                    'because no measurement unit is selected.']);
                selectedNodes = [];
            end
            
            % check if all selected nodes are measurement units
            selectedMUnitNodes = cell(1,length(selectedNodes));
            for i=1:length(selectedNodes)
                handle = selectedNodes(i).NodeData;
                measData = app.femtoapiObj.getMeasurementMetaData(handle);
                if(~isfield(measData,'type') || ~strcmp(measData.type,'measurement'))
                    errordlg(strcat(['Could not batch export curves, '...
                        'because not all selected items are measurement units.']));
                    selectedNodes = [];
                end
                selectedMUnitNodes{i} = handle;
            end
        end


        function selectAndExportCurves(app, selectedMUnitNodes, channelType)
            commonCurveMetaDataTable = app.femtoapiObj.getCommonCurvesMetaDataTable(selectedMUnitNodes,channelType);
            if(~isempty(commonCurveMetaDataTable))
                browser_batch_export_curves_App(app,unique(commonCurveMetaDataTable.curveName,'stable'));
                if( ~isempty(app.absolutePath) )
                    % save files
                    absoluteFilePath = strcat(app.absolutePath,app.fileName);
                    if(strcmp(app.ext,'.txt'))
                        exportedFileNames = app.femtoapiObj.batchExportCurvesTxt(commonCurveMetaDataTable,selectedMUnitNodes,app.selectedChannelsForExport,absoluteFilePath);
                    elseif(strcmp(app.ext,'.mat'))
                        exportedFileNames = app.femtoapiObj.batchExportCurvesMat(commonCurveMetaDataTable,selectedMUnitNodes,app.selectedChannelsForExport,absoluteFilePath);
                    else
                        uiwait(errordlg('Could not save curve data to file, because the selected file extension is not supported.'));
                        return;
                    end
                    
                    uiwait(msgbox({'Curve data from selected measurement units and channels were successfully exported to '; ...
                        sprintf('%s\n',exportedFileNames{:})}));

                end
            else
                uiwait(msgbox(strcat("No common ", lower(channelType)," channels were found in the selected measurement units.")));
            end
        end
        
        function exportMeasurementMetaData(app, selectedMUnitNodes)
            
            % file path selector, and enter batch file name for export
            filter = {'*.json'};
            
            [app.fileName, app.absolutePath, ~] = uiputfile(filter,'Select path','exported_metadata');
            
            if(app.fileName ~= 0) % if user hasn't cancelled dialog box
                try
                    app.ext = strrep(filter{1},'*','');
                    app.fileName = strrep(app.fileName,app.ext,'');
                    app.absolutePath = strrep(app.absolutePath,'\','/');
                    app.fileName = strcat(app.absolutePath,app.fileName);
                    
                    exportedFileNames = app.femtoapiObj.batchExportMeasurementMetaData(selectedMUnitNodes,app.fileName);
                    
                    uiwait(msgbox({'Measurement metadata json of selected units were successfully exported to file(s) '; ...
                        sprintf('%s\n',exportedFileNames{:})}));
                catch ME
                    uiwait(errordlg({'Error while exporting measurement metadata. Cause: '; ...
                        sprintf('%s\n',ME.message)}));
                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = [];
            refreshDataButtonPushed(app, event);
        end

        % Selection changed function: uitreeObj
        function uitreeObjSelectionChanged(app, event)
            selectedNodes = app.uitreeObj.SelectedNodes;

            % one node is surely selected
            if(~isempty(selectedNodes))
                handle = selectedNodes(1).NodeData;
                app.treeNodeStruct = app.femtoapiObj.getMeasurementMetaData(handle);
            else
                app.treeNodeStruct = [];
            end
            
            % set info text
            setInfoText(app);
            
            % set LUT names in popup menu when selected node is channel
            if(isfield(app.treeNodeStruct,'channels') && ...
                    isfield(app.treeNodeStruct,'dimensions') && ...
                    ismember(length(app.treeNodeStruct.dimensions),[2,3]))
                
                channelNum = length(app.treeNodeStruct.channels);
                channelNames = cell(channelNum,1);
                for i=1:channelNum
                    channelNames{i,1} = app.treeNodeStruct.channels(i).data.name;
                end
                set(app.lutChannelsPopupMenu,'Items', channelNames);
                set(app.lutChannelsPopupMenu,'ItemsData',[1:length(channelNames)]);
            else
                set(app.lutChannelsPopupMenu,'Items', {''});
                set(app.lutChannelsPopupMenu,'ItemsData',1);
            end
                        
            set(app.errorMessageText,'Text','');
            
            if(isfield(app.treeNodeStruct,'dimensions') && ismember(length(app.treeNodeStruct.dimensions),[2,3]))
                dimSize = length(app.treeNodeStruct.dimensions);
                % set up slider
                if(dimSize == 3)
                    zDimSize = app.treeNodeStruct.dimensions(3).size;
                    set(app.slider1,'Limits',[1,zDimSize]);
                    set(app.slider1,'Value',1);
                else
                    set(app.slider1,'Limits',[0,1]);
                    set(app.slider1,'Value',0);
                end
                
                % set slider value edit box
                set(app.frameNumText,'Value','1');
                
                % make label conversion
                % check rotation, and set the labeling according to it
                if(~isfield(app.treeNodeStruct,'rotationQuaternion'))
                    error('Internal error: No fieldname rotationQuaternion is present.');
                end
                rotationQuaternion = app.treeNodeStruct.rotationQuaternion;
                conversionStructX = app.treeNodeStruct.dimensions(1).conversion;
                conversionStructY = app.treeNodeStruct.dimensions(2).conversion;
                sizeX = app.treeNodeStruct.dimensions(1).size;
                sizeY = app.treeNodeStruct.dimensions(2).size;
                
                app.xLabel = 1:sizeX;
                app.yLabel = 1:sizeY;
                
                app.xLabel = convertAxisLabel(app.xLabel,conversionStructX);
                app.yLabel = convertAxisLabel(app.yLabel,conversionStructY);
                
                % [0;0;0;1] - no rotation, in case of rotation, no change
                if(isequal(rotationQuaternion,[0;0;0;1]))
                    app.xLabel = app.xLabel + app.treeNodeStruct.translation(1) - app.treeNodeStruct.labelingOrigin(1);
                    app.yLabel = app.yLabel + app.treeNodeStruct.translation(2) - app.treeNodeStruct.labelingOrigin(2);
                end
                
                %choose 5 points for labeling and save the labels
                app.xLabel = linspace(app.xLabel(1),app.xLabel(end),5);
                app.yLabel = linspace(app.yLabel(1),app.yLabel(end),5);
                app.xTicks = fix(linspace(1,app.treeNodeStruct.dimensions(1).size,5));
                app.yTicks = fix(linspace(1,app.treeNodeStruct.dimensions(2).size,5));   
                app.xlabelText = strcat(app.treeNodeStruct.dimensions(1).conversion.title,'[',...
                    app.treeNodeStruct.dimensions(1).conversion.unitName,...
                    ']');
                app.ylabelText = strcat(app.treeNodeStruct.dimensions(2).conversion.title,'[',...
                    app.treeNodeStruct.dimensions(2).conversion.unitName,...
                    ']');
            else
                set(app.frameNumText,'Value','');
            end
            
            if(isfield(app.treeNodeStruct,'dimensions') && ~ismember(length(app.treeNodeStruct.dimensions),[2,3]))
                set(app.errorMessageText,'Text','Only data that are 2 or 3D can be shown.');
            end
            
            app.frameNum = 1;
            show_frame(app);
        end

        % Value changed function: slider1
        function slider1ValueChanged(app, event)
            %value = app.slider1.Value;
            
        end

        % Value changing function: slider1
        function slider1ValueChanging(app, event)
            sliderValue = round(event.Value);            
            app.frameNum = sliderValue;
            set(app.frameNumText,'Value',num2str(sliderValue));
            show_frame(app);
        end

        % Button pushed function: refreshDataButton
        function refreshDataButtonPushed(app, event)
            app.femtoapiObj = FemtoAPIProcessing('ws://localhost:8888');
            
            buildUITree(app);
            %app.uitreeObj.SelectionType = 'discontiguous';
            
            app.frameNum = 1;
            set(app.slider1,'Value',1);
            set(app.frameNumText,'Value','');

            % set selected nodes empty
            app.treeNodeStruct = [];

            % clear info panel
            setInfoText(app)
            
            % clear channel name and set value to 1
            set(app.lutChannelsPopupMenu,'Items',{''});
            set(app.lutChannelsPopupMenu,'ItemsData',1);
            
            % show empty frame
            show_frame(app);
            
        end

        % Value changed function: frameNumText
        function frameNumTextValueChanged(app, event)
            %value = app.frameNumText.Value;
            selectedNodes = app.uitreeObj.SelectedNodes;
            
            if(~isempty(selectedNodes))
                treeNodeData = app.femtoapiObj.getMeasurementMetaData(selectedNodes(1).NodeData);
            end
            
            if(isempty(selectedNodes) || ...
                    ~isfield(treeNodeData,'channels') )
                set(app.errorMessageText,'Text',['Frame can only be set if '...
                    'the selected item is a measurement data.']);
                return;
            end
            
            sliderValue = fix(str2double(get(app.frameNumText,'Value')));
            if(isnan(sliderValue) || ~isscalar(sliderValue))
                set(app.errorMessageText,'Text','Frame number must be a numeric value.')
                return;
            end
            set(app.errorMessageText,'Text','');
            limits = get(app.slider1,'Limits');
            sliderMin = limits(1);
            sliderMax = limits(2);
            sliderValue = clamp(sliderMin, sliderValue ,sliderMax);
            app.frameNum = sliderValue;
            
            set(app.slider1,'Value',sliderValue);
            set(app.frameNumText,'Value',num2str(sliderValue));
            show_frame(app);
        end

        % Button pushed function: batchExportInputCurvesButton
        function batchExportInputCurvesButtonPushed(app, event)
            % gather selected nodes into cell array
            selectedMUnitNodes = checkSelectedMUnitNodes(app);
            selectAndExportCurves(app, selectedMUnitNodes, 'Input');
        end

        % Button pushed function: batchExportOutputCurvesButton
        function batchExportOutputCurvesButtonPushed(app, event)
            selectedMUnitNodes = checkSelectedMUnitNodes(app);
            selectAndExportCurves(app, selectedMUnitNodes, 'Output');
        end

        % Button pushed function: batchExportMeasurementParamsButton
        function batchExportMeasurementParamsButtonPushed(app, event)
            selectedMUnitNodes = checkSelectedMUnitNodes(app);
            exportMeasurementMetaData(app, selectedMUnitNodes);
        end

        % Value changed function: lutChannelsPopupMenu
        function lutChannelsPopupMenuValueChanged(app, event)
            % get tree item -> if it is not a measurement, just return
            if(~isfield(app.treeNodeStruct,'channels') )
                disp('Not a measurement unit');
                return;
            end
            
            % show frame
            show_frame(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.femtoapiObj.disconnect();
            delete(app)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FemtoAPI browser';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.GridLayout = uigridlayout(app.figure1);
            app.measTreeUipanel = uipanel(app.GridLayout);
            app.measTreeUipanel.Tooltip = {''};
            app.measTreeUipanel.Title = 'Measurements';
            app.uitreeObj = uitree(app.measTreeUipanel);
            app.uitreeObj.Multiselect = 'on';
            app.uitreeObj.SelectionChangedFcn = createCallbackFcn(app, @uitreeObjSelectionChanged, true);
            app.infoTableUiPanel = uipanel(app.GridLayout);
            app.infoTableUiPanel.Tooltip = {''};
            app.infoTableUiPanel.Title = 'Summary';
            app.infoTable = uitable(app.infoTableUiPanel);
            app.infoTable.ColumnName = {''; ''};
            app.infoTable.RowName = {''};
            app.infoTable.ColumnEditable = [false false];
            app.uipanel6 = uipanel(app.GridLayout);
            app.uipanel6.Title = 'Controls';
            app.refreshDataButton = uibutton(app.uipanel6, 'push');
            app.refreshDataButton.ButtonPushedFcn = createCallbackFcn(app, @refreshDataButtonPushed, true);
            app.refreshDataButton.Text = 'Refresh data';
            app.batchExportInputCurvesButton = uibutton(app.uipanel6, 'push');
            app.batchExportInputCurvesButton.ButtonPushedFcn = createCallbackFcn(app, @batchExportInputCurvesButtonPushed, true);
            app.batchExportInputCurvesButton.Text = 'Batch export input curves...';
            app.batchExportOutputCurvesButton = uibutton(app.uipanel6, 'push');
            app.batchExportOutputCurvesButton.ButtonPushedFcn = createCallbackFcn(app, @batchExportOutputCurvesButtonPushed, true);
            app.batchExportOutputCurvesButton.Text = 'Batch export output curves...';
            app.batchExportMeasurementParamsButton = uibutton(app.uipanel6, 'push');
            app.batchExportMeasurementParamsButton.ButtonPushedFcn = createCallbackFcn(app, @batchExportMeasurementParamsButtonPushed, true);
            app.batchExportMeasurementParamsButton.Text = 'Batch export measurementParams';
            app.uipanel7 = uipanel(app.GridLayout);
            app.uipanel7.Title = 'Visualization';
            app.text2 = uilabel(app.uipanel7);
            app.text2.Text = 'Channel';
            app.lutChannelsPopupMenu = uidropdown(app.uipanel7);
            app.lutChannelsPopupMenu.Items = {};
            app.lutChannelsPopupMenu.ValueChangedFcn = createCallbackFcn(app, @lutChannelsPopupMenuValueChanged, true);
            app.lutChannelsPopupMenu.Value = {};
            app.text3 = uilabel(app.uipanel7);
            app.text3.Text = 'Frame number:';
            app.slider1 = uislider(app.uipanel7);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.MajorTickLabels = {''};
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1ValueChanged, true);
            app.slider1.ValueChangingFcn = createCallbackFcn(app, @slider1ValueChanging, true);
            app.slider1.MinorTicks = [];
            app.frameNumText = uieditfield(app.uipanel7, 'text');
            app.frameNumText.ValueChangedFcn = createCallbackFcn(app, @frameNumTextValueChanged, true);
            app.errorMessageText = uilabel(app.uipanel7);
            app.errorMessageText.Text = '';
            app.axes1 = uiaxes(app.uipanel7);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.XTick = [0 1];
            app.axes1.XTickLabel = {'0'; '1'};
            app.axes1.YTick = [0 1];
            app.axes1.YTickLabel = {'0'; '1'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = femtoapi_browser_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end