classdef measurement_control_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasurementcontroldemoUIFigure  matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        UpdatemeasurementparametersButton  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ResonantTab                     matlab.ui.container.Tab
        resonantInfoTable               matlab.ui.control.Table
        GalvoTab                        matlab.ui.container.Tab
        galvoInfoTable                  matlab.ui.control.Table
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        measurementunithandleEditFieldLabel  matlab.ui.control.Label
        measurementunithandleEditField  matlab.ui.control.EditField
        frameindexLabel                 matlab.ui.control.Label
        frameindexEditField             matlab.ui.control.EditField
        SetmeasurementdurationButton    matlab.ui.control.Button
        durationEditField               matlab.ui.control.NumericEditField
        StopButton                      matlab.ui.control.Button
        durationLabel                   matlab.ui.control.Label
        InfoLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
% Copyright ©2021. Femtonics Kft. (Femtonics). All Rights Reserved. 
% Permission to use, copy, modify this software and its documentation for educational,
% research, and not-for-profit purposes, without fee and without a signed licensing agreement, is 
% hereby granted, provided that the above copyright notice, this paragraph and the following two 
% paragraphs appear in all copies, modifications, and distributions. Contact info@femtonics.eu
% for commercial licensing opportunities.
% 
% IN NO EVENT SHALL FEMTONICS BE LIABLE TO ANY PARTY FOR DIRECT, INDIRECT, SPECIAL, 
% INCIDENTAL, OR CONSEQUENTIAL DAMAGES, INCLUDING LOST PROFITS, ARISING OUT OF 
% THE USE OF THIS SOFTWARE AND ITS DOCUMENTATION, EVEN IF FEMTONICS HAS BEEN 
% ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
% 
% FEMTONICS SPECIFICALLY DISCLAIMS ANY WARRANTIES, INCLUDING, BUT NOT LIMITED TO, 
% THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR 
% PURPOSE. THE SOFTWARE AND ACCOMPANYING DOCUMENTATION, IF ANY, PROVIDED 
% HEREUNDER IS PROVIDED "AS IS". FEMTONICS HAS NO OBLIGATION TO PROVIDE 
% MAINTENANCE, SUPPORT, UPDATES, ENHANCEMENTS, OR MODIFICATIONS.
    
    properties (Access = private)
        femtoapiAcquisitionObj % FemtoAPIAcquisition object
        femtoapiProcessingObj
        activeTask % active measurement tab in MESc
        activeMeasurementHandle % handle of the last measurement unit in the active measurement session
    end
    
    properties (Access = private, Constant)
        startedText = "Measurement is running"
        stoppedText = "No measurement is running"
    end
    
    methods (Access = private)
        function updateInfoTablesAndDuration(app)
            infoStr = struct;
            
            microscopeState = app.femtoapiAcquisitionObj.getMicroscopeState().microscopeState; 
            
            %% set galvo table
            galvoParams = app.femtoapiAcquisitionObj.getTaskParameters('galvo');  
            
            frameDimensions = sprintf('%d x %d', galvoParams.frameWidthInPixels.sizeX, galvoParams.frameWidthInPixels.sizeY);
            xyPixelSize = sprintf('%.5f %s x %.5f %s', galvoParams.xyPixelSize.pixelSizeX, ' um', ...
                galvoParams.xyPixelSize.pixelSizeY, ' um');
            
            % duration
            if(galvoParams.isDurationInfinite)
                durationEntered = "NaN";
                durationEstimated = "NaN";
            else
                durationEntered = sprintf('%.2f s', galvoParams.durationEntered);
                if(~isempty( galvoParams.durationEstimated ))
                    durationEstimated = sprintf('%.2f s', galvoParams.durationEstimated);
                else
                    durationEstimated = "-";
                end
            end
            
            averagedFramesPerShownFrame = sprintf('%d', galvoParams.averagedFramesPerShownFrame);
            relativeScannerAmplitude = sprintf('%d', galvoParams.relativeScannerAmplitude);
            pixelDwellTime = sprintf('%.2f %s',galvoParams.pixelDwellTime, 'us');
            xScanningFrequency = sprintf('%.2f Hz', galvoParams.xScanningFrequency);
            rateOfScannedFrames = sprintf('%f Hz', galvoParams.rateOfScannedFrames);
            
            
            % put all together into a column vectors
            [subTaskParamNames, subTaskParamValues] = app.getSubTaskParameters('galvo');
            infoStr.column1 = string(["Microscope state";"Measurement type";"XY Pixel size";"Frame dimensions";"Rate of scanned frames";"Relative scanner amplitude";...
                "Entered duration"; "Estimated duration";"Averaged frames per shown frame";"Pixel dwell time";"X scanning frequency";subTaskParamNames]);
            
            infoStr.column2 = string([microscopeState.toCharArray; galvoParams.measurementMode; xyPixelSize; frameDimensions; ...
                rateOfScannedFrames; relativeScannerAmplitude; durationEntered; durationEstimated; averagedFramesPerShownFrame; ...
                pixelDwellTime; xScanningFrequency; subTaskParamValues]);
            
            set(app.galvoInfoTable,'Data',[infoStr.column1 infoStr.column2]);


            %% set resonant table
            resonantParams = app.femtoapiAcquisitionObj.getTaskParameters('resonant'); %state.taskParametersPerSpace.resonant;
            frameDimensions = sprintf("%d x %d",resonantParams.frameWidthInPixels.sizeX, resonantParams.frameWidthInPixels.sizeY);
            xyPixelSize = sprintf("%.5f %s x %.5f %s", resonantParams.xyPixelSize.pixelSizeX, " [um]", ...
                resonantParams.xyPixelSize.pixelSizeY, " [um]");
            
            % duration
            if(resonantParams.isDurationInfinite)
                durationEntered = "NaN";
                durationEstimated = "NaN";
            else
                durationEntered = sprintf("%.2f s", resonantParams.durationEntered);
                if(~isempty( resonantParams.durationEstimated ))
                    durationEstimated = sprintf("%.2f s", resonantParams.durationEstimated);
                else
                    durationEstimated = "-";
                end
            end
            
            averagedFramesPerShownFrame = sprintf('%d', resonantParams.averagedFramesPerShownFrame);
            relativeScannerAmplitude = sprintf('%d', resonantParams.relativeScannerAmplitude);
            rateOfScannedFrames = sprintf('%f Hz', resonantParams.rateOfScannedFrames);
            
            % put all together into a column vectors
            [subTaskParamNames, subTaskParamValues] = app.getSubTaskParameters('resonant');
            
            infoStr.column1 = string(["Microscope state";"Measurement type";"XY Pixel size";"Frame dimensions";"Rate of scanned frames";"Relative scanner amplitude";...
                "Entered duration"; "Estimated duration"; "Averaged frames per shown frame";subTaskParamNames]);
            infoStr.column2 = string([microscopeState.toCharArray; resonantParams.measurementMode; xyPixelSize; frameDimensions; ...
                rateOfScannedFrames; relativeScannerAmplitude; durationEntered; durationEstimated; averagedFramesPerShownFrame; subTaskParamValues]);
            
            set(app.resonantInfoTable,'Data',[infoStr.column1 infoStr.column2]);
            
            % update duration edit box with active task's duration
            if(strcmp(app.activeTask,'resonant'))
                app.durationEditField.Value = resonantParams.durationEntered;
            else
                app.durationEditField.Value = galvoParams.durationEntered;
            end
            
        end


        function [parameterNames, values] = getSubTaskParameters(app, taskType)
            subTaskType = app.femtoapiAcquisitionObj.getActiveSubTaskForTask(taskType);
            parmeters = app.femtoapiAcquisitionObj.getActiveSubTaskParameters(taskType);
            
            if(strcmp(subTaskType, 'zStack'))
                parameterNames = strings(6,1);
                values = strings(6,1);
                
                parameterNames(1) = "FirstZ";
                values(1) =  sprintf('%.2f',parmeters.firstZ);
                parameterNames(2) = "IntermediateZ";
                values(2) =  sprintf('%.2f',parmeters.IntermediateZ);
                parameterNames(3) = "LastZ";
                values(3) =  sprintf('%.2f',parmeters.lastZ);
                parameterNames(4) = "StepZ";
                values(4) =  sprintf('%.2f',parmeters.stepZ);
                parameterNames(5) = "ZPlanes";
                values(5) =  sprintf('%.2f',parmeters.zPlanes);
                parameterNames(6) = "Averaging";
                values(6) =  sprintf('%.2f',parmeters.averaging);
                
            elseif(strcmp(subTaskType,'volumeScan'))
                parameterNames = strings(5,1);
                values = strings(5,1);
                
                parameterNames(1) = "Mode";
                values(1) =  parmeters.mode;
                parameterNames(2) = "Planes";
                values(2) =  sprintf('%d',parmeters.planes);
                parameterNames(3) = "Average";
                values(3) =  sprintf('%d',parmeters.average);
                parameterNames(4) = "First";
                values(4) =  sprintf('%.2f',parmeters.first);
                parameterNames(5) = "Last";
                values(5) =  sprintf('%.2f',parmeters.last);
                
            else
                parameterNames = "";
                values = "";
            end
        end
        
        function setActiveTask(app)
            activeTaskGet = app.femtoapiAcquisitionObj.getActiveTaskAndSubTask();
            
            if(strcmp(activeTaskGet,'resonant'))
                app.activeTask = "resonant";
            elseif(strcmp(activeTaskGet,'galvo'))
                app.activeTask = "galvo";
            else
                error(strcat('Invalid task type ',activeTaskGet));
            end
        end
        
        
        function readAndShowImage(app)
            chnum = app.ChannelDropDown.Value;
            
            % get metadata of the last measurement in the session
            measData = app.femtoapiProcessingObj.getMeasurementMetaData(app.activeMeasurementHandle);
            dimX = measData.dimensions(1).size;
            dimY = measData.dimensions(2).size;
            dimZ = measData.dimensions(3).size;
            
            channelHandle = [app.activeMeasurementHandle, chnum-1]; 
            
            frameNumToShow = ceil(dimZ / 2); % show some from the middle
            
            % select some frame from the 'middle' of measurement unit
            image = readChannelData(app.femtoapiProcessingObj, channelHandle, 'uint16', {[1:dimX], [1:dimY],[frameNumToShow:frameNumToShow]} );
            
            % set LUT of the image
            lutObj = LUT(measData.channels(chnum).data.clut);
            image = lutObj.convertChannelToARGB(image);
            
            imagesc(app.UIAxes,image(:,:,2:4));
            
            % set active measurement unit and framNumToShow in the edit
            % boxes
            app.frameindexEditField.Value = num2str(frameNumToShow);
            app.measurementunithandleEditField.Value = num2str(app.activeMeasurementHandle);
        end
        
        
        function updateAxes(app)
            % make label conversion
            % check rotation, and set the labeling according to it
            activeMeasurementMetaData = app.femtoapiProcessingObj.getMeasurementMetaData(app.activeMeasurementHandle);
            
            if(isempty(activeMeasurementMetaData))
                error('No active measurement has been set.');
            end
            
            if(~isfield(activeMeasurementMetaData,'rotationQuaternion'))
                error('No fieldname rotationQuaternion is present.');
            end
            
            rotationQuaternion = activeMeasurementMetaData.rotationQuaternion;
            conversionStructX = activeMeasurementMetaData.dimensions(1).conversion;
            conversionStructY = activeMeasurementMetaData.dimensions(2).conversion;
            sizeX = activeMeasurementMetaData.dimensions(1).size;
            sizeY = activeMeasurementMetaData.dimensions(2).size;
            
            xLabel = 1:sizeX;
            yLabel = 1:sizeY;
            
            xLabel = convertAxisLabel(xLabel,conversionStructX);
            yLabel = convertAxisLabel(yLabel,conversionStructY);
            
            % [0;0;0;1] - no rotation, in case of rotation, no change
            if(isequal(rotationQuaternion,[0;0;0;1]))
                xLabel = xLabel + activeMeasurementMetaData.translation(1) - activeMeasurementMetaData.labelingOrigin(1);
                yLabel = yLabel + activeMeasurementMetaData.translation(2) - activeMeasurementMetaData.labelingOrigin(2);
            end
            
            %choose 5 points for labeling and save the labels
            xLabel = linspace(xLabel(1),xLabel(end),5);
            yLabel = linspace(yLabel(1),yLabel(end),5);
            
            xTicks = fix(linspace(1,activeMeasurementMetaData.dimensions(1).size,5));
            yTicks = fix(linspace(1,activeMeasurementMetaData.dimensions(2).size,5));
            
            xlabelText = strcat(activeMeasurementMetaData.dimensions(1).conversion.title,'[',...
                activeMeasurementMetaData.dimensions(1).conversion.unitName,...
                ']');
            ylabelText = strcat(activeMeasurementMetaData.dimensions(2).conversion.title,'[',...
                activeMeasurementMetaData.dimensions(2).conversion.unitName,...
                ']');
            
            %% set value to UIAxes
            app.UIAxes.reset;
            app.UIAxes.XAxisLocation = 'bottom';
            app.UIAxes.YAxisLocation = 'left';
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.XLim = [-Inf inf];
            app.UIAxes.XTickMode = 'manual';
            app.UIAxes.XTick = xTicks;
            
            app.UIAxes.XTickLabelMode = 'manual';
            app.UIAxes.XTickLabel = sprintfc('%d',fix(xLabel));
            app.UIAxes.XMinorTick = 'On';
            
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.YLim = [-Inf inf];
            app.UIAxes.YTickMode = 'manual';
            app.UIAxes.YTick = yTicks;
            app.UIAxes.YTickLabelMode = 'manual';
            app.UIAxes.YTickLabel = sprintfc('%d',fliplr(fix(yLabel)));
            app.UIAxes.YMinorTick = 'On';
            
            xlabel(app.UIAxes,xlabelText);
            ylabel(app.UIAxes,ylabelText);
        end
        
        function updateChannelDropDown(app)
            activeMeasData = app.femtoapiProcessingObj.getMeasurementMetaData(app.activeMeasurementHandle);
            channelNum = length(activeMeasData.channels);
            for i=1:channelNum
                app.ChannelDropDown.ItemsData(i) = i;
                app.ChannelDropDown.Items{i} = activeMeasData.channels(i).data.name;
            end
        end
        
        
        function success = setActiveMeasurementHandle(app)
            currentsessionHandle = app.femtoapiProcessingObj.getCurrentSessionHandle;
            numOfMeasurements = app.femtoapiProcessingObj.getNumOfChildHandles(currentsessionHandle);
            if(numOfMeasurements == 0)
                waitfor(warndlg('There are no measurements in the active measurement session.'));
                app.activeMeasurementHandle = [];
                success = false;
                return;
            end
            measIdx = numOfMeasurements -1;
            % set active measurement handle = last measurement handle
            % of the active session
            app.activeMeasurementHandle = reshape([currentsessionHandle; measIdx], 1,[]);
            success = true;
        end
        
        
        % wait for measurement to finish -> when finished, updates
        % measurement parameters or shows error
        function waitforStopAndUpdateParameters(app, varargin)
            measurementState = app.femtoapiAcquisitionObj.getMicroscopeState;
            if(length(varargin) == 1)
                validateattributes(varargin{1},{'numeric'},{'scalar','positive'});
                timeout = varargin{1} + 100; % wait for measurement complete timeout (must be greater or equal to measurement duration)
            else
                timeout = 100; % stopping measurement timeout
            end
            
            time = 0;
            while(time < timeout)
                pause(1)
                measurementState = app.femtoapiAcquisitionObj.getMicroscopeState;
                if(measurementState.microscopeState ~= MicroscopeState.Working)
                    break;
                end
                time = time + 1;
            end
            
            microscopeState = measurementState.microscopeState;
            lastMeasurementError = measurementState.lastMeasurementError;
            if(~isempty(lastMeasurementError))
                bWasError = true;
            else
                bWasError = false;
            end
            
            if(bWasError)
                errordlg(strcat(['There was an error during measurement: ',lastMeasurementError, '. Microscope is in ',microscopeState.toCharArray, ' state.']));
            elseif(microscopeState == MicroscopeState.Working) % if still in working state, increase timeout
                waitfor(warndlg('Measurement is still running. Increase stop timeout and try again.'));
                return;
            elseif(measurementState.microscopeState == MicroscopeState.Off)
                warndlg('Measurement stopped. Hardware layer is off.');
            elseif(measurementState.microscopeState == MicroscopeState.Invalid)
                errordlg('Measurement stopped. An error happened during measurement. Reason: unknown.');
            elseif(measurementState.microscopeState == MicroscopeState.Initializing)
                warndlg('Could not stop measurement, because hardware is initializing. ');
            else
                % ready state -> update measurement parameters and gui components, show one frame from measurement
                UpdateMeasurementParametersButtonPushed(app,[]);
            end
            
            app.InfoLabel.Text = app.stoppedText;
        end
        
        function updateAcquisitionParameters(app)
            if( ~app.femtoapiAcquisitionObj.isConnected() )
                try
                    app.femtoapiAcquisitionObj.connnect();
                catch ME
                    waitfor(errordlg("Could not connect to the server."));
                    return;
                end
            end
            app.femtoapiAcquisitionObj.getAcquisitionState();
            app.femtoapiProcessingObj.getProcessingState();
            app.setActiveTask();
            
            % set tab to active task
            if(app.activeTask == "resonant")
                app.TabGroup.SelectedTab = app.ResonantTab;
            else
                app.TabGroup.SelectedTab = app.GalvoTab;
            end
            
            app.updateInfoTablesAndDuration();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.femtoapiAcquisitionObj = FemtoAPIAcquisition(); % 'ws://192.168.80.19:8888'
                app.femtoapiProcessingObj = FemtoAPIProcessing(); % 'ws://192.168.80.19:8888'
            catch ME
                waitfor(errordlg(ME.message));
                return;
            end
            
            app.measurementunithandleEditField.Value = "";
            app.frameindexEditField.Value = "";
            app.InfoLabel.Text = app.stoppedText;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if(app.TabGroup.SelectedTab == app.ResonantTab)
                try
                    app.femtoapiAcquisitionObj.startResonantScanAsync();
                catch ME
                    waitfor(errordlg(strcat('Failed to start resonant measurement. Reason: ',ME.message)));
                    return;
                end
            else
                try
                    app.femtoapiAcquisitionObj.startGalvoScanAsync();
                catch ME
                    waitfor(errordlg(strcat('Failed to start galvo measurement. Reason: ', ME.message)));
                    return;
                end
            end
            
            % wait for measurement to start -> when started, it is in
            % working state -> get measurement parameters
            timeout = 10; % time to wait for measurement start
            time = 0;
            while( time < timeout )
                pause(1);
                measurementState = app.femtoapiAcquisitionObj.getMicroscopeState;
                if(measurementState.microscopeState == MicroscopeState.Working )
                    break;
                end
                time = time + 1;
            end
            
            % microscope should be in working state
            if( measurementState.microscopeState ~= MicroscopeState.Working )
                failReason = "Unknown";
                if(~isempty(measurementState.lastMeasurementError))
                    failReason = measurementState.lastMeasurementError;
                end
                waitfor(errordlg(strcat(['Could not start measurement. Reason: ',failReason, '. Microscope is in ', ...
                    measurementState.microscopeState.toCharArray, ' state.'])));
                return;
            else
                % set info label
                app.InfoLabel.Text = app.startedText;
                
                if(app.durationEditField.Value == 0)
                    % in case if infinite measurement --> press stop button
                    msgbox('Measurement has been started. You have to stop it manually by pressing Stop button.');
                else
                    % finite measurement -> wait for measurement to stop,
                    % then update parameters
                    msgbox('Measurement has been started. It will stop automatically when duration ends.');
                    waitforStopAndUpdateParameters(app,app.durationEditField.Value);
                end
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(app.TabGroup.SelectedTab == app.ResonantTab)
                try
                    app.femtoapiAcquisitionObj.stopResonantScanAsync();
                catch ME
                    waitfor(errordlg(strcat('Failed to stop resonant measurement. Reason: ',ME.message)));
                    return;
                end
            else
                try
                    app.femtoapiAcquisitionObj.stopGalvoScanAsync();
                catch ME
                    waitfor(errordlg(strcat('Failed to stop galvo measurement. Reason: ', ME.message)));
                    return;
                end
            end
            
            waitforStopAndUpdateParameters(app);
        end

        % Button pushed function: UpdatemeasurementparametersButton
        function UpdateMeasurementParametersButtonPushed(app, event)
            app.updateAcquisitionParameters();
            
            % update parameters of measured image 
            app.femtoapiProcessingObj.getProcessingState();
            app.updateInfoTablesAndDuration();
            if(app.setActiveMeasurementHandle())
                app.updateChannelDropDown();
                app.updateAxes();
                
                % get first image from measurement
                app.readAndShowImage();
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
        % get and show 1st measured image of the selected channel
        app.readAndShowImage();
        end

        % Button pushed function: SetmeasurementdurationButton
        function SetmeasurementDurationButtonPushed(app, event)
        try
            if(isempty(app.durationEditField.Value))
                waitfor(msgbox('Please enter the measurement duration and try again.'));
                return;
            end
            if( app.TabGroup.SelectedTab == app.ResonantTab)
                app.femtoapiAcquisitionObj.setMeasurementDuration(app.durationEditField.Value, 'resonant');
            else
                app.femtoapiAcquisitionObj.setMeasurementDuration(app.durationEditField.Value, 'galvo');
            end
        catch ME
            waitfor(errordlg(strcat('Could not set measurement duration. ',ME.message)));
        end
        
        app.updateAcquisitionParameters();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
        selectedTab = app.TabGroup.SelectedTab;
        if(selectedTab == app.ResonantTab)
            taskName = 'resonant';
        else
            taskName = 'galvo';
        end
        
        taskParameters = app.femtoapiAcquisitionObj.getTaskParameters(taskName);
        if(taskParameters.isDurationInfinite)
            app.durationEditField.Value = 0;
        else
            app.durationEditField.Value = taskParameters.durationEntered;
        end
        end

        % Close request function: MeasurementcontroldemoUIFigure
        function MeasurementcontroldemoUIFigureCloseRequest(app, event)
            if(~isempty(app.femtoapiAcquisitionObj) && app.femtoapiAcquisitionObj.isConnected())
                app.femtoapiAcquisitionObj.disconnect();
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasurementcontroldemoUIFigure = uifigure('Visible', 'off');
            app.MeasurementcontroldemoUIFigure.Name = 'Measurement control demo';
            app.MeasurementcontroldemoUIFigure.CloseRequestFcn = createCallbackFcn(app, @MeasurementcontroldemoUIFigureCloseRequest, true);
            app.StartButton = uibutton(app.MeasurementcontroldemoUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UpdatemeasurementparametersButton = uibutton(app.MeasurementcontroldemoUIFigure, 'push');
            app.UpdatemeasurementparametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMeasurementParametersButtonPushed, true);
            app.UpdatemeasurementparametersButton.Text = 'Update measurement parameters';
            app.TabGroup = uitabgroup(app.MeasurementcontroldemoUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ResonantTab = uitab(app.TabGroup);
            app.ResonantTab.Title = 'Resonant';
            app.resonantInfoTable = uitable(app.ResonantTab);
            app.resonantInfoTable.ColumnName = {'Parameter Name'; 'Parameter value'};
            app.resonantInfoTable.RowName = {};
            app.resonantInfoTable.ColumnSortable = true;
            app.GalvoTab = uitab(app.TabGroup);
            app.GalvoTab.Title = 'Galvo';
            app.galvoInfoTable = uitable(app.GalvoTab);
            app.galvoInfoTable.ColumnName = {'Parameter Name'; 'Parameter value'};
            app.galvoInfoTable.RowName = {};
            app.galvoInfoTable.ColumnSortable = true;
            app.ChannelDropDownLabel = uilabel(app.MeasurementcontroldemoUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.MeasurementcontroldemoUIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.measurementunithandleEditFieldLabel = uilabel(app.MeasurementcontroldemoUIFigure);
            app.measurementunithandleEditFieldLabel.Text = 'measurement unit handle: ';
            app.measurementunithandleEditField = uieditfield(app.MeasurementcontroldemoUIFigure, 'text');
            app.measurementunithandleEditField.Editable = 'off';
            app.frameindexLabel = uilabel(app.MeasurementcontroldemoUIFigure);
            app.frameindexLabel.Text = 'frame index: ';
            app.frameindexEditField = uieditfield(app.MeasurementcontroldemoUIFigure, 'text');
            app.frameindexEditField.Editable = 'off';
            app.SetmeasurementdurationButton = uibutton(app.MeasurementcontroldemoUIFigure, 'push');
            app.SetmeasurementdurationButton.ButtonPushedFcn = createCallbackFcn(app, @SetmeasurementDurationButtonPushed, true);
            app.SetmeasurementdurationButton.Text = 'Set measurement duration';
            app.durationEditField = uieditfield(app.MeasurementcontroldemoUIFigure, 'numeric');
            app.StopButton = uibutton(app.MeasurementcontroldemoUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.durationLabel = uilabel(app.MeasurementcontroldemoUIFigure);
            app.durationLabel.Text = 'duration: ';
            app.InfoLabel = uilabel(app.MeasurementcontroldemoUIFigure);
            app.InfoLabel.Text = '';
            app.UIAxes = uiaxes(app.MeasurementcontroldemoUIFigure);
            title(app.UIAxes, 'Frame from recorded measurement')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.MeasurementcontroldemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = measurement_control_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasurementcontroldemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasurementcontroldemoUIFigure)
        end
    end
end